classdef app1_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        TabGroup2          matlab.ui.container.TabGroup
        simulation_part    matlab.ui.container.Tab
        simualte_UIAxes    matlab.ui.control.UIAxes
        leidastate_part    matlab.ui.container.Tab
        boatstate_part     matlab.ui.container.Tab
        wY                 matlab.ui.control.UIAxes
        wP                 matlab.ui.control.UIAxes
        wR                 matlab.ui.control.UIAxes
        P                  matlab.ui.control.UIAxes
        Y                  matlab.ui.control.UIAxes
        R                  matlab.ui.control.UIAxes
        vW                 matlab.ui.control.UIAxes
        vV                 matlab.ui.control.UIAxes
        vU                 matlab.ui.control.UIAxes
        GCG_Z              matlab.ui.control.UIAxes
        GCG_Y              matlab.ui.control.UIAxes
        GCG_X              matlab.ui.control.UIAxes
        TabGroup           matlab.ui.container.TabGroup
        environment_part   matlab.ui.container.Tab
        end_siumulate      matlab.ui.control.Button
        begin_simuliate    matlab.ui.control.Button
        vwind10            matlab.ui.control.NumericEditField
        mmsEditFieldLabel  matlab.ui.control.Label
        vboat              matlab.ui.control.NumericEditField
        Label_20           matlab.ui.control.Label
        wave_degree        matlab.ui.control.NumericEditField
        Label_19           matlab.ui.control.Label
        sea_state          matlab.ui.control.NumericEditField
        Label_18           matlab.ui.control.Label
        vboat_pan          matlab.ui.control.Knob
        wave_type          matlab.ui.control.DropDown
        Label_14           matlab.ui.control.Label
        leida_part         matlab.ui.container.Tab
        TextArea_10        matlab.ui.control.TextArea
        Label_11           matlab.ui.control.Label
        TextArea_9         matlab.ui.control.TextArea
        Label_10           matlab.ui.control.Label
        title              matlab.ui.control.Label
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function begin_simuliateValueChanged(app, event)
            % 获取用户输入的参数
            wavesStruct.seaState = app.sea_state.Value;
            wavesStruct.beta = app.wave_degree.Value;
            shipStruct.refSpeedU = app.vboat.Value;
            wavesStruct.U= app.vboat.Value;
            Vwind10 = app.vwind10.Value;
            wavesStruct.waveType = app.wave_type.Value;

            %波形构建需要的网格、时间值（不同demo不一样，记得看）
            wavesStruct.xVec     = linspace(0, 299, 300);
            wavesStruct.yVec     = linspace(0, 99, 100);
            wavesStruct.Ts       = 0.2;
            wavesStruct.tVec     = 0:wavesStruct.Ts:200;

            % %短波需要的参数
            % dmu      = pi/20;
            % muVec    = -pi/2:dmu:pi/2;


            %调用函数创建波形
            wavesStruct.waves = app_simulateWaves(app,wavesStruct.seaState, ...
                                           wavesStruct.xVec, wavesStruct.yVec, ...
                                           wavesStruct.beta, wavesStruct.tVec, ...
                                           wavesStruct.U,[],muVec,dmu);

            wavesStruct.displayName = [
           'Waves with properties: ', ...
           '\n   -Sea state: ', num2str(wavesStruct.seaState), ...
           '\n   -Significant wave height: ', num2str(getSignificantWaveHeight(wavesStruct.seaState)), ' m' ...
           '\n   -', wavesStruct.waveType, ' crested' ...
           '\n   -Main wave direction (beta): ', num2str(wavesStruct.beta), ' rad', ...
           '\n   -xVec: ', num2str(wavesStruct.xVec(1)), ':', num2str(1), ':', num2str(wavesStruct.xVec(end)), ' m', ...
           '\n   -yVec: ', num2str(wavesStruct.yVec(1)), ':', num2str(1), ':', num2str(wavesStruct.yVec(end)), ' m', ...
           '\n   -tVec: ', num2str(1), ':', num2str(wavesStruct.Ts), ':', num2str(wavesStruct.tVec(end)), ' s', ...
           '\n   -U: ', num2str(wavesStruct.U), ' m/s', ...
           '\n'];
            saveWavesFile(wavesStruct);

            function saveWavesFile(wavesStruct)
            fileName = ['wave-files/waves__seaState_', num2str(wavesStruct.seaState), '__', ... 
                        wavesStruct.waveType, ...
                        '__beta_', num2str(round(wavesStruct.beta, 2)), ...
                        '__grid_', num2str(length(wavesStruct.xVec)), 'x', num2str(length(wavesStruct.yVec)), ...
                        '__time_0_', num2str(wavesStruct.Ts), '_', num2str(wavesStruct.tVec(end)), ...  
                        '__U_', num2str(wavesStruct.U),...
                        '.mat'];
            save(fileName, 'wavesStruct');
            % disp(['Saved waves into file `', fileName, '`']);
            end
            
            %船只参数
            shipStruct.file        = 'filteredNorfolkNew.stl';
            shipStruct.M           = 2.5e6;
            shipStruct.len         = 137;
            shipStruct.width       = 15;
            shipStruct.height      = 16;
            shipStruct.verticesPos = [35 70 5.55];
            shipStruct.cogOffset   = [-4.77 0.022 -2];
            shipStruct.refSpeedU   = 0;%初始速度吗
            shipStruct.refYaw      = 0;
            shipStruct.helipadPos  = [40 35 6];
            %               [v_u v_v v_w phi th psi w_phi w_th w_psi]'
            shipStruct.x0 = [0    0   0  0   0  0   0     0    0]; % Initial state values

            shipStruct.refSpeedU = app.vboat.Value;
            %调用函数创建船，我把所有显示进程的语句都注释了，后续再看能不能创建一个窗口加上
            [states, face, vert, cogVec] = app_simulateShip(app,waveFile, shipStruct, true, true);
        end

        % Callback function
        function wave_typeClicked(app, event)
            item = event.InteractionInformation.Item;
            
        end

        % Callback function
        function wave_typeDropDownOpening(app, event)
            
        end

        % Value changed function: wave_type
        function wave_typeValueChanged(app, event)
            wavesStruct.waveType = app.wave_type.Value;
            % 根据波浪类型更新其他参数或界面
            if strcmp(wavesStruct.waveType, 'long')
                % 长峰波，清空短峰波相关参数
                muVec = [];
                dmu = [];
            else
                % 短峰波，设置默认的方向向量和间隔
                dmu = pi/20;
                muVec = -pi/2:dmu:pi/2;
            end
        end

        % Callback function
        function sea_state_SliderValueChanged(app, event)
            % % 获取滑块的当前值
            % wavesStruct.seaState = app.sea_state_Slider.Value;
            % 
            % % 更新编辑框的值
            % app.sea_state.Value = wavesStruct.seaState;
        end

        % Value changed function: sea_state
        function sea_stateValueChanged(app, event)
            % 获取编辑框的当前值
            wavesStruct.seaState = app.sea_state.Value;
            
            % % 更新滑块的值
            % app.sea_state_Slider.Value = wavesStruct.seaState;
            
        end

        % Value changed function: wave_degree
        function wave_degreeValueChanged(app, event)
            % 获取波浪传播角度
            wavesStruct.beta = app.wave_degree.Value;
            
            % 更新角度显示
            app.wave_degree.Value = wavesStruct.beta;
        end

        % Value changed function: vboat
        function vboatValueChanged(app, event)
            % 获取编辑框的当前值
            shipStruct.refSpeedU = app.vboat.Value;
            
            % 更新旋钮的值
            app.vboat_pan.Value = shipStruct.refSpeedU;
        end

        % Value changed function: vwind10
        function vwind10ValueChanged(app, event)
            % 获取风速
            Vwind10 = app.vwind10.Value;
            
            % 更新风速显示
            app.vwind10.Value = Vwind10;
        end

        % Value changed function: vboat_pan
        function vboat_panValueChanged(app, event)
            % 获取旋钮的当前值
            shipStruct.refSpeedU = app.vboat_pan.Value;
            
            % 更新编辑框的值
            app.vboat.Value = shipStruct.refSpeedU;
        end

        % Button pushed function: begin_simuliate
        function begin_simuliateButtonPushed(app, event)
            % Add paths to wave-files, help-files
            % wavesPath = [pwd, '\wave-files'];
            % helpFilesPath = [pwd, '\help-files'];
            % boatPath = [pwd, '\boat-files'];
            % addpath(wavesPath);
            % addpath(helpFilesPath);
            % addpath(boatPath);

            wavesPath = fullfile(pwd, 'wave-files');
            helpFilesPath = fullfile(pwd, 'help-files');
            boatPath = fullfile(pwd, 'boat-files');
            addpath(wavesPath);
            addpath(helpFilesPath);
            addpath(boatPath);

            % 获取用户输入的参数
            % seaState = app.sea_state.Value;
            % beta = app.wave_degree.Value;
            % refSpeedU = app.vboat.Value;
            % U= app.vboat.Value;
            % Vwind10 = app.vwind10.Value;
            % waveType = app.wave_type.Value;
            wavesStruct.seaState = app.sea_state.Value;
            wavesStruct.beta = app.wave_degree.Value;
            shipStruct.refSpeedU = app.vboat.Value;
            wavesStruct.U= app.vboat.Value;
            Vwind10 = app.vwind10.Value;
            wavesStruct.waveType = app.wave_type.Value;

            %波形构建需要的网格、时间值（不同demo不一样，记得看）
            % xVec     = linspace(0, 299, 300);
            % yVec     = linspace(0, 99, 100);
            % Ts       = 0.2;
            % tVec     = 0:wavesStruct.Ts:200;
            wavesStruct.xVec     = linspace(0, 299, 300);
            wavesStruct.yVec     = linspace(0, 99, 100);
            wavesStruct.Ts       = 0.2;
            wavesStruct.tVec     = 0:wavesStruct.Ts:200;

            
            % %短波需要的参数
            % dmu      = pi/20;
            % muVec    = -pi/2:dmu:pi/2;
            
            % 根据波浪类型更新其他参数或界面
            if strcmp(wavesStruct.waveType, 'long')
                % 长峰波，清空短峰波相关参数
                muVec = [];
                dmu = [];
            else
                % 短峰波，设置默认的方向向量和间隔
                dmu = pi/20;
                muVec = -pi/2:dmu:pi/2;
            end
               
    % 
    %           % 打印所有参数
    % disp('Parameters passed to app_simulateWaves:');
    % disp(['seaState: ', num2str(wavesStruct.seaState)]);
    % disp(['xVec: ', num2str(wavesStruct.xVec)]);
    % disp(['yVec: ', num2str(wavesStruct.yVec)]);
    % disp(['beta: ', num2str(wavesStruct.beta)]);
    % disp(['tVec: ', num2str(wavesStruct.tVec)]);
    % disp(['U: ', num2str(wavesStruct.U)]);
    % disp(['waveType: ', wavesStruct.waveType]);
    % disp(['muVec: ', num2str(muVec)]);
    % disp(['dmu: ', num2str(dmu)]);

            %调用函数创建波形
            wavesStruct.waves = app_simulateWaves(app,wavesStruct.seaState, ...
                                           wavesStruct.xVec, wavesStruct.yVec, ...
                                           wavesStruct.beta, wavesStruct.tVec, ...
                                           wavesStruct.U,[],muVec,dmu);

            % helpFilesPath = [pwd, '\help-files'];
            % addpath(helpFilesPath);

            wavesStruct.displayName = [
           'Waves with properties: ', ...
           '\n   -Sea state: ', num2str(wavesStruct.seaState), ...
           '\n   -Significant wave height: ', num2str(getSignificantWaveHeight(wavesStruct.seaState)), ' m' ...
           '\n   -', wavesStruct.waveType, ' crested' ...
           '\n   -Main wave direction (beta): ', num2str(wavesStruct.beta), ' rad', ...
           '\n   -xVec: ', num2str(wavesStruct.xVec(1)), ':', num2str(1), ':', num2str(wavesStruct.xVec(end)), ' m', ...
           '\n   -yVec: ', num2str(wavesStruct.yVec(1)), ':', num2str(1), ':', num2str(wavesStruct.yVec(end)), ' m', ...
           '\n   -tVec: ', num2str(1), ':', num2str(wavesStruct.Ts), ':', num2str(wavesStruct.tVec(end)), ' s', ...
           '\n   -U: ', num2str(wavesStruct.U), ' m/s', ...
           '\n'];
            saveWavesFile(wavesStruct);

            % % 获取生成的 waveFile 文件路径
            % waveFile = fileName; % 这里的 fileName 是 saveWavesFile 函数中生成的文件名

            % 保存波浪场数据到文件并获取文件名
            waveFile = saveWavesFile(wavesStruct);

            function fileName = saveWavesFile(wavesStruct)
            fileName = ['wave-files/waves__seaState_', num2str(wavesStruct.seaState), '__', ... 
                        wavesStruct.waveType, ...
                        '__beta_', num2str(round(wavesStruct.beta, 2)), ...
                        '__grid_', num2str(length(wavesStruct.xVec)), 'x', num2str(length(wavesStruct.yVec)), ...
                        '__time_0_', num2str(wavesStruct.Ts), '_', num2str(wavesStruct.tVec(end)), ...  
                        '__U_', num2str(wavesStruct.U),...
                        '.mat'];
            save(fileName, 'wavesStruct');
            end
          

            % % 加载波浪场数据
            % wavesStruct = load(waveFile);
            % wavesStruct.xVec = wavesStruct.xVec;
            % wavesStruct.yVec = wavesStruct.yVec;
            % wavesStruct.tVec = wavesStruct.tVec;
            % wavesStruct.Ts = wavesStruct.Ts;

            %船只参数
            shipStruct.file        = 'filteredNorfolkNew.stl';
            shipStruct.M           = 2.5e6;
            shipStruct.len         = 137;
            shipStruct.width       = 15;
            shipStruct.height      = 16;
            shipStruct.verticesPos = [35 70 5.55];
            shipStruct.cogOffset   = [-4.77 0.022 -2];
            shipStruct.refSpeedU   = 0;%初始速度吗
            shipStruct.refYaw      = 0;
            shipStruct.helipadPos  = [40 35 6];
            %               [v_u v_v v_w phi th psi w_phi w_th w_psi]'
            shipStruct.x0 = [0    0   0  0   0  0   0     0    0]; % Initial state values

            shipStruct.refSpeedU = app.vboat.Value;
            %调用函数创建船，我把所有显示进程的语句都注释了，后续再看能不能创建一个窗口加上
            [states, face, vert, cogVec] = app_simulateShip(app,waveFile, shipStruct, true, true);
        end

        % Button pushed function: end_siumulate
        function end_siumulateButtonPushed(app, event)
            % 清除姿态数据显示图表
            cla(app.GCG_X);
            cla(app.GCG_Y);
            cla(app.GCG_Z);
            cla(app.vU);
            cla(app.vV);
            cla(app.vW);
            cla(app.P);
            cla(app.Y);
            cla(app.R);
            cla(app.wP);
            cla(app.wY);
            cla(app.wR);

            %清除仿真
            app.begin_simuliate=[];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.title = uilabel(app.UIFigure);
            app.title.Text = '————  船载平台地波雷达仿真系统  ————';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.environment_part = uitab(app.TabGroup);
            app.environment_part.Title = '环境和船只参数';
            app.Label_14 = uilabel(app.environment_part);
            app.Label_14.Text = '波的类型';
            app.wave_type = uidropdown(app.environment_part);
            app.wave_type.Items = {'long', 'short'};
            app.wave_type.ValueChangedFcn = createCallbackFcn(app, @wave_typeValueChanged, true);
            app.wave_type.Value = 'long';
            app.vboat_pan = uiknob(app.environment_part, 'continuous');
            app.vboat_pan.Limits = [0 10];
            app.vboat_pan.ValueChangedFcn = createCallbackFcn(app, @vboat_panValueChanged, true);
            app.Label_18 = uilabel(app.environment_part);
            app.Label_18.Text = '海况条件（级）';
            app.sea_state = uieditfield(app.environment_part, 'numeric');
            app.sea_state.ValueChangedFcn = createCallbackFcn(app, @sea_stateValueChanged, true);
            app.Label_19 = uilabel(app.environment_part);
            app.Label_19.Text = '波的传播角度(弧度)';
            app.wave_degree = uieditfield(app.environment_part, 'numeric');
            app.wave_degree.ValueChangedFcn = createCallbackFcn(app, @wave_degreeValueChanged, true);
            app.Label_20 = uilabel(app.environment_part);
            app.Label_20.Text = '船只速度（m/s）';
            app.vboat = uieditfield(app.environment_part, 'numeric');
            app.vboat.ValueChangedFcn = createCallbackFcn(app, @vboatValueChanged, true);
            app.mmsEditFieldLabel = uilabel(app.environment_part);
            app.mmsEditFieldLabel.Text = '10m处的风速（m/s）';
            app.vwind10 = uieditfield(app.environment_part, 'numeric');
            app.vwind10.ValueChangedFcn = createCallbackFcn(app, @vwind10ValueChanged, true);
            app.begin_simuliate = uibutton(app.environment_part, 'push');
            app.begin_simuliate.ButtonPushedFcn = createCallbackFcn(app, @begin_simuliateButtonPushed, true);
            app.begin_simuliate.Text = '开始仿真';
            app.end_siumulate = uibutton(app.environment_part, 'push');
            app.end_siumulate.ButtonPushedFcn = createCallbackFcn(app, @end_siumulateButtonPushed, true);
            app.end_siumulate.Text = '结束仿真';
            app.leida_part = uitab(app.TabGroup);
            app.leida_part.Title = '雷达参数';
            app.Label_10 = uilabel(app.leida_part);
            app.Label_10.Text = '天线仰角';
            app.TextArea_9 = uitextarea(app.leida_part);
            app.Label_11 = uilabel(app.leida_part);
            app.Label_11.Text = '天线方向角';
            app.TextArea_10 = uitextarea(app.leida_part);
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.simulation_part = uitab(app.TabGroup2);
            app.simulation_part.Title = '仿真';
            app.simualte_UIAxes = uiaxes(app.simulation_part);
            title(app.simualte_UIAxes, 'Title')
            xlabel(app.simualte_UIAxes, 'X')
            ylabel(app.simualte_UIAxes, 'Y')
            zlabel(app.simualte_UIAxes, 'Z')
            app.leidastate_part = uitab(app.TabGroup2);
            app.leidastate_part.Title = '雷达显示';
            app.boatstate_part = uitab(app.TabGroup2);
            app.boatstate_part.Title = '船只姿态数据';
            app.GCG_X = uiaxes(app.boatstate_part);
            title(app.GCG_X, 'Title')
            app.GCG_Y = uiaxes(app.boatstate_part);
            title(app.GCG_Y, 'Title')
            app.GCG_Z = uiaxes(app.boatstate_part);
            title(app.GCG_Z, 'Title')
            app.vU = uiaxes(app.boatstate_part);
            title(app.vU, 'Title')
            app.vV = uiaxes(app.boatstate_part);
            title(app.vV, 'Title')
            app.vW = uiaxes(app.boatstate_part);
            title(app.vW, 'Title')
            app.R = uiaxes(app.boatstate_part);
            title(app.R, 'Title')
            app.Y = uiaxes(app.boatstate_part);
            title(app.Y, 'Title')
            app.P = uiaxes(app.boatstate_part);
            title(app.P, 'Title')
            app.wR = uiaxes(app.boatstate_part);
            title(app.wR, 'Title')
            app.wP = uiaxes(app.boatstate_part);
            title(app.wP, 'Title')
            app.wY = uiaxes(app.boatstate_part);
            title(app.wY, 'Title')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end